classdef SynthesisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InitializationPanel         matlab.ui.container.Panel
        ControlMethodDropDownLabel  matlab.ui.control.Label
        ControlMethodDropDown       matlab.ui.control.DropDown
        StartDrawingButton          matlab.ui.control.Button
        SynthesisPanel              matlab.ui.container.Panel
        SynthesizeButton            matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        mEditFieldLabel             matlab.ui.control.Label
        mEditField                  matlab.ui.control.NumericEditField
        nLabel                      matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        SegmentCurveButton          matlab.ui.control.Button
        CurvatureSliderLabel        matlab.ui.control.Label
        CurvatureSlider             matlab.ui.control.Slider
        TorsionSliderLabel          matlab.ui.control.Label
        TorsionSlider               matlab.ui.control.Slider
        ArcLengthSliderLabel        matlab.ui.control.Label
        ArcLengthSlider             matlab.ui.control.Slider
        SelectCurveDropDownLabel    matlab.ui.control.Label
        SelectCurveDropDown         matlab.ui.control.DropDown
        RemoveSelectedCurveButton   matlab.ui.control.Button
        TDSliderLabel               matlab.ui.control.Label
        TDSlider                    matlab.ui.control.Slider
        PlotAxes                    matlab.ui.control.UIAxes
        ModelSelectionPanel         matlab.ui.container.Panel
        LoadSTLFileLabel            matlab.ui.control.Label
        LoadFileButton              matlab.ui.control.Button
        STLDropDown                 matlab.ui.control.DropDown
        ClearSTLButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        % The current curve to draw
        curves; % Array to hold all the individual curves
        % Curve object consists of:
        %   arc - individual points of the arc
        %   l - The arc length
        %   kappa - The K(s) function k(l, arclength)
        %   tau - The T(s) function tau(l, arclength)
        %   t - The tangeant vector of the frenet frame
        %   n - The normal vector of the frenet frame
        %   b - The binormal vector of the frenet frame
        
        partitioncurves; % The curve with dominant points
        
        % Curve Generation parameters
        %         k; % The K(s) curvature-arclength function
        %         tau; % The T(s) torsion-arclength function
        %         arcLength = 20e-3;
        %         variableK = 100;
        %         m;
        %         n;
        %         a = 1;
        %         b = 1;
        
        X;
        Y;
        Z;
        
        robots; % Array of robots
        rotation = 0;
        proximalRotation = 0;
        
        % Controller Parameters
        arduinoController;
        startWaitFlag = false;
        keyboard = 0;
        
        %% Drawing parameters
        % The STL file currently loaded
        stlFileName; % The name of the uploaded .STL file
        stlFilePath; % The path of the uploaded .STL file
        meMesh; % Middle-ear Mesh
        osMesh; % Ossicle Mesh
        totalMesh; % The total mesh of the inner ear
        transform = eye(4); % Transform from Mesh to Base
        mePatch; % Middle ear STL patch
        osPatch; % Ossicle STL patch
        loadedSTLName;
        
        localTransforms = []; 
        
        scatterPlot;
        scatterPartition;
        robotPlot;
        col = distinguishable_colors(10);
    end
    
    methods (Access = private)
        
        % Search in the anatomical-models folder for all STL folders
        function findSTLFolders(app)
            files2 = dir("anatomical-models\");
            files2(1:2) = [];
            dirFlags = [files2.isdir];
            subFolders = files2(dirFlags);
            
            newDrop = {};
            
            % Iterate through all the subfolders and build the new dropdown
            for k = 1 : length(subFolders)
                newDrop(k) = cellstr(subFolders(k).name);
            end
            
            app.STLDropDown.Items = newDrop;
        end
        
        function resetCOMDropdown(app)
            COMports = seriallist;
            
            result = ["Keyboard"];
            for i = 1 : size(COMports, 2)
                result(i + 1) = COMports(i);
            end
            
            app.ControlMethodDropDown.Items = string(result);
        end
        
        function updateSliders(app, curveNumber)
            % Update the sliders to the selected curve number
            fprintf("Curve number %d", curveNumber);
            curve = app.curves(curveNumber);
            
            curve.kConstant
            app.CurvatureSlider.Value = curve.kConstant;
            curve.l(end)
            app.ArcLengthSlider.Value = curve.l(end);
            app.TorsionSlider.Value = curve.tauConstant;
        end
    end
    
    methods (Access = public)
        
        function generateCurve(app)
            % Append a new curve to the end of the array
            arcLength = 0.01;
            variableK = 100;
            k = @(s, arcLength) variableK .* ones(1, length(s));
            tau = @(s, arcLength) 0 .* ones(1, length(s));
            
            if isempty(app.transform)
                curve = makecurve(arcLength, k, tau, 'kConstant', variableK, 'tauConstant', 0, 'rotation', app.rotation);
            else
                curve = makecurve(arcLength, k, tau, 'kConstant', variableK, 'tauConstant', 0, 'rotation', app.rotation, 'transform', app.transform);
            end
            
            if ~isempty(app.curves)
                
                app.curves = [app.curves curve];
            else
                % Means we are generating first curve
                
                app.curves = [curve];
            end
            
        end
        
        function draw(app)
            delete(app.scatterPlot);
            numCurves = size(app.curves, 2);
            drawArc = [];
            
            for i = 1 : numCurves
                curve = app.curves(i);
                drawArc = [drawArc curve.arc];
            end
            
            drawArc = drawArc .* 1000;
            drawArc = applytransform(drawArc, app.transform);
            
            app.scatterPlot = scatter3(app.PlotAxes, drawArc(1,:), drawArc(2,:), drawArc(3,:),'MarkerEdgeColor', app.col(5,:), 'LineWidth', 1.5);
            
            if ~isempty(app.partitioncurves)
                delete(app.scatterPartition);
                
                numCurves = size(app.partitioncurves, 2);
                drawArc = [];
                
                for i = 1: numCurves
                    curve = app.partitioncurves(i);
                    drawArc = [drawArc curve.arc];
                    
                end
                drawArc = drawArc .* 1000;
                dominantPts = applytransform(drawArc, app.transform);
                app.scatterPartition = scatter3(app.PlotAxes, dominantPts(1,:), dominantPts(2,:), dominantPts(3,:), 'filled', 'MarkerEdgeColor', app.col(9,:), 'LineWidth', 3.5);
            end
            
            xlim(app.PlotAxes, 'auto');
            ylim(app.PlotAxes, 'auto');
            zlim(app.PlotAxes, 'auto');
            axis(app.PlotAxes, 'equal');
        end
        
        % update
        function update(app)
            if app.startWaitFlag
                app.arduinoController.updateNunchukValues();
            end
            
            numCurves = size(app.curves, 2);
            
            app.localTransforms = cell(1, numCurves);
            % fprintf("Starting loop \n");
            
            for i = 1 : numCurves
                oldCurve = app.curves(i);
                
                if i == 1
                    % Do nothing
                    localTransform = eye(4);
                    localTransform(1:3, 1:3) = [cos(app.rotation) -sin(app.rotation) 0; ...
                        sin(app.rotation) cos(app.rotation)  0; ...
                        0          0           1];
                else
                    if i == numCurves
                        tipTransform = eye(4);
                        tipTransform(1:3, 1:3) = [cos(app.proximalRotation) -sin(app.proximalRotation) 0; ...
                                                    sin(app.proximalRotation) cos(app.proximalRotation)  0; ...
                                                    0          0           1];
                        localTransform = app.curves(i - 1).nextTransform * tipTransform;
                    else
                        localTransform = app.curves(i - 1).nextTransform;
                    end
                end
                
                app.localTransforms{i} = localTransform ;
                newCurve = makecurve(oldCurve.l(end), oldCurve.kappas, oldCurve.taus,  oldCurve.kConstant, oldCurve.tauConstant, ...
                    'transform', localTransform);
                
                if numCurves == 1
                    app.curves = newCurve;
                else
                    app.curves(i) = newCurve;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            set(app.UIFigure, 'DoubleBuffer','on')
            findSTLFolders(app)
            resetCOMDropdown(app)
            generateCurve(app)
            updateSliders(app, 1)
        end

        % Callback function: LoadFileButton, STLDropDown
        function loadSTL(app, event)
            % Load an STL if the Load File button is pressed
            
            delete(app.mePatch);
            delete(app.osPatch);
            
            value = app.STLDropDown.Value;
            app.loadedSTLName = value;
            % fprintf("Loading: %s\n ", value);
            
            if strlength(value) > 0
                % Read the STL using Professor Fichera's code
                [meModel, transform, meModelRayCast, meSegmentation] = loadEarModel(value);
                
                % Store the values from the mesh
                app.transform = transform;
                % app.transform(1,4) = app.transform(1,4) + 10;
                app.meMesh = meModel.me;
                app.osMesh = meModel.os;
                
                % Construct a total mesh used for ray-casting
                points = vertcat(app.meMesh.Vertices, app.osMesh.Vertices);
                faceUpdate = app.osMesh.Faces + max(max(app.meMesh.Faces));
                faces = vertcat(app.meMesh.Faces, faceUpdate);
                
                app.totalMesh.Vertices = points;
                app.totalMesh.Faces = faces;
                
                % Draw the STLs
                app.mePatch = patch(app.PlotAxes, app.meMesh, 'FaceAlpha', 0.5, 'FaceColor', '#2b2e33', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                app.osPatch = patch(app.PlotAxes, app.osMesh, 'LineWidth', 0.2, 'FaceColor', '#071938', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                
                % Reset camera orientation and position and axis limits
                campos(app.PlotAxes, 'auto');
                xlim(app.PlotAxes, 'auto');
                ylim(app.PlotAxes, 'auto');
                zlim(app.PlotAxes, 'auto');
                axis(app.PlotAxes, 'equal');
            else
                disp("Illegal filename: Filename cannot be empty")
            end
        end

        % Button pushed function: ClearSTLButton
        function removeSTL(app, event)
            % Clear the axes
            delete(app.osPatch);
            delete(app.mePatch);
            
            % Reset all the parameters that were set before the load
            app.transform = eye(4);
            app.PlotAxes.XLim = [-15 15];
            app.PlotAxes.YLim = [-15 15];
            app.PlotAxes.ZLim = [0 15];
        end

        % Value changed function: ControlMethodDropDown
        function updateCOMDropdown(app, event)
            value = app.ControlMethodDropDown.Value;
            resetCOMDropdown(app); % Force an update of the dropdown
        end

        % Button pushed function: StartDrawingButton
        function connectArduino(app, event)
            app.keyboard = 0;
            dropdownValue = app.ControlMethodDropDown.Value;
            
            if strcmp(dropdownValue, "Keyboard")
                app.keyboard = 1;
                app.startWaitFlag = 1;
                fprintf("Selected keyboard \n");
            else
                
                COMPort = app.ControlMethodDropDown.Value;
                
                % Ensure that the COM port is a number greater than 0
                if ~isempty(COMPort)
                    fprintf("Selected port: %s, Starting Arduino \n", COMPort);
                    try
                        app.arduinoController = arduinoController(COMPort);
                        app.startWaitFlag = 1;
                    catch
                        app.keyboard = 1;
                        app.startWaitFlag = 1;
                    end
                end
            end
        end

        % Key press function: UIFigure
        function keypress(app, event)
            key = event.Key;
            switch key
                case 'w'
                    % forward, W key is pressed
                    app.variableK = app.variableK + 1;
                case 's'
                    % backward, S key is pressed
                    app.variableK = app.variableK - 1;
                case 'a'
                    % left, A key is pressed
                    app.proximalRotation = app.proximalRotation + 0.1;
                    app.proximalRotation = mod(app.proximalRotation, (2 * pi));
                case 'd'
                    % right, d key is pressed
                    app.proximalRotation = app.proximalRotation - 0.1;
                    app.proximalRotation = mod(app.proximalRotation, (2 * pi));
                case 'q'
                    app.rotation = app.rotation + 0.1;
                    app.rotation = mod(app.rotation, (2 * pi));
                case 'e'
                    app.rotation = app.rotation - 0.1;
                    app.rotation = mod(app.rotation, (2 * pi));
            end
        end

        % Button pushed function: SegmentCurveButton
        function segmentCurve(app, event)
            
            numCurves = size(app.curves, 2);
            
            % fprintf("Starting loop \n");
            
            partitioncurves = [];
            for i = 1 : numCurves
                
                newCurve = partitioncurve(app.curves(i), app.mEditField.Value);
                partitioncurves = [partitioncurves newCurve];
                
            end
            app.partitioncurves = partitioncurves;
        end

        % Value changed function: ArcLengthSlider, CurvatureSlider, 
        % TorsionSlider
        function updateCurve(app, event)
            curveNumber = str2num(app.SelectCurveDropDown.Value);
            newArcLength = app.ArcLengthSlider.Value;
            newCurvature = app.CurvatureSlider.Value;
            newTorsion = app.TorsionSlider.Value;
            
            curve = app.curves(curveNumber);
            curve.l = linspace(0, newArcLength, size(curve.l, 2));
            curve.tauConstant = newTorsion;
            curve.taus = @(s,arcLength) newTorsion * ones(1, length(s));
            curve.kConstant = newCurvature;
            curve.kappas = @(s,arcLength) newCurvature .* ones(1, length(s));
            
            if size(app.curves, 2) == 1
                app.curves = curve;
            end
            app.curves(curveNumber) = curve;
        end

        % Value changed function: SelectCurveDropDown
        function updateSelectedCurve(app, event)
            % Check if there needs to be an extra notch
            value = app.SelectCurveDropDown.Value;
            if strcmp(value, "Add")
                fprintf("Attempting to add \n")
                result = "";
                for i = 1 : size(app.SelectCurveDropDown.Items, 2)
                    result(i) = i;
                end
                
                result(i + 1) = "Add";
                
                app.SelectCurveDropDown.Items = string(result);
                app.SelectCurveDropDown.Value = string(i);
                app.generateCurve();
                app.updateCurve();
                app.updateSliders(i);
            else
                app.updateSliders(str2num(value));
            end
        end

        % Button pushed function: SynthesizeButton
        function synthesize(app, event)
            
            
            numCurves = size(app.curves, 2);
            
            app.X = cell(1, numCurves);
            app.Y = cell(1, numCurves);
            app.Z = cell(1, numCurves);
            app.robots = cell(1, numCurves);
            for i = 1 : numCurves
                if ~isempty(app.robotPlot)
                    delete(app.robotPlot{i});
                end
                m = app.mEditField.Value;
                partitionedCurve = app.partitioncurves(i);
                
                OD = 1.85 * 10^-3; % [m] tube outer diameter
                ID = 1.60 * 10^-3; % [m] tube inner diameter
                ro = OD/2;         % [m] tube outer radius
                ri = ID/2;         % [m] tube inner radius
                
                l = diff(partitionedCurve.l);
                kappa = partitionedCurve.averageKappa;
                
                w = .85 * OD; % [m]
                d = w - ro;   % [m]
                phio = 2 * acos(d / ro); % [rad]
                phii = 2 * acos(d / ri); % [rad]
                ybaro = (4 * ro * (sin(0.5 * phio)) ^ 3)/ (3 * (phio - sin(phio)));
                ybari = (4 * ri * (sin(0.5 * phii)) ^ 3)/ (3 * (phio - sin(phii)));
                Ao = ( (ro ^ 2) * ( phio - sin(phio))) / 2;
                Ai = ( (ri ^ 2) * ( phii - sin(phii))) / 2;
                ybar = (ybaro * Ao - ybari * Ai) / (Ao - Ai);
                
                % number of notches
                n = 1 * ones(1, m);
                
                % height of the notches and of the uncut sections
                h = zeros(1, m);
                u = zeros(1, m);
                T = eye(4);
                
                cutouts.w = [];
                cutouts.u = [];
                cutouts.h = [];
                cutouts.alpha = [];
                
                for ii = 1 : m
                    h(ii) = kappa(ii) * l(ii) * (ro+ybar)/n(ii);
                    u(ii) = (l(ii) - kappa(ii)*l(ii)*ro)/n(ii);
                    
                    cutouts.w = [cutouts.w w*10^3 .* ones(1,n(ii))];
                    cutouts.u = [cutouts.u u(ii)*10^3 .* ones(1,n(ii))];
                    cutouts.h = [cutouts.h h(ii)*10^3 .* ones(1,n(ii))];
                    cutouts.alpha = [cutouts.alpha zeros(1,n(ii))];
                end
                
                %configuration{ii} = [h(ii)*10^3, 0, 0];

                if i == numCurves
                    configuration = [app.TDSlider.Value, 0, app.proximalRotation];
                else
                    configuration = [app.TDSlider.Value, 0, 0];
                end
                
                robot = Wrist(ID*10^3, OD*10^3, sum(n), cutouts);
                robot.fwkine(configuration, T);
                robotModel = robot.makePhysicalModel();
                app.robots{i} = robot;
                
                
                app.X{i} = robotModel.surface.X;
                app.Y{i} = robotModel.surface.Y;
                app.Z{i} = robotModel.surface.Z;
            end
            
            app.robotPlot = cell(1, numCurves);
            
            previousTrans = eye(4);
            previousX = 0;
            previousY = 0;
            previousZ = 0;
            
            for i = 1: numCurves
                rowc = size(app.X{i}, 1);
                colc = size(app.X{i}, 2);
                

                app.X{i} = reshape(app.X{i}, 1, []);
                app.Y{i} = reshape(app.Y{i}, 1, []);
                app.Z{i} = reshape(app.Z{i}, 1, []);
                        
                
                wristTransform =  app.localTransforms{i};
                
                transformed = applytransform([app.X{i}; app.Y{i}; app.Z{i}], wristTransform);
                transformed2 = applytransform(transformed, app.transform);
                app.X{i} = transformed2(1, :);
                app.Y{i} = transformed2(2, :);
                app.Z{i} = transformed2(3, :);
                
                app.X{i} = reshape(app.X{i}, rowc, colc) ;
                app.Y{i} = reshape(app.Y{i}, rowc, colc) ;
                app.Z{i} = reshape(app.Z{i}, rowc, colc) ;
                
                app.robotPlot{i} = surface(app.PlotAxes, real(app.X{i} + previousX), real(app.Y{i} + previousY), real(app.Z{i} + previousZ), 'FaceColor', app.col(6,:));
                
                backbone = app.robots{i}.robotModel.backbone;
                transformedbackbone = applytransform(backbone, app.transform * wristTransform);
                
                previousX = transformedbackbone(1, end) - transformedbackbone(1, 1);
                previousY = transformedbackbone(2, end) - transformedbackbone(2, 1);
                previousZ = transformedbackbone(3, end) - transformedbackbone(3, 1);
            end
            
            
        end

        % Button pushed function: RemoveSelectedCurveButton
        function deleteCurve(app, event)
            value = app.SelectCurveDropDown.Value;
            if length(app.curves) > 1
                if strcmp(value, "Add")
                    fprintf("Please select a curve, not Add");
                else
                    % fprintf("Removing %d", str2num(value));
                    app.curves(str2num(value)) = [];
                    
                    result = "";
                    for i = 1 : size(app.curves, 2)
                        result(i) = i;
                    end
                    result(i + 1) = "Add";
                    
                    app.SelectCurveDropDown.Items = string(result);
                    app.SelectCurveDropDown.Value = string(i);
                    app.updateSelectedCurve();
                    app.updateSliders(i);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @keypress, true);
            app.InitializationPanel = uipanel(app.UIFigure);
            app.InitializationPanel.Title = 'Initialization';
            app.ControlMethodDropDownLabel = uilabel(app.InitializationPanel);
            app.ControlMethodDropDownLabel.Text = 'Control Method';
            app.ControlMethodDropDown = uidropdown(app.InitializationPanel);
            app.ControlMethodDropDown.Items = {'Keyboard', ''};
            app.ControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @updateCOMDropdown, true);
            app.ControlMethodDropDown.Value = 'Keyboard';
            app.StartDrawingButton = uibutton(app.InitializationPanel, 'push');
            app.StartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.StartDrawingButton.Text = 'Start Drawing';
            app.SynthesisPanel = uipanel(app.UIFigure);
            app.SynthesisPanel.Title = 'Synthesis';
            app.SynthesizeButton = uibutton(app.SynthesisPanel, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @synthesize, true);
            app.SynthesizeButton.Text = 'Synthesize';
            app.Panel = uipanel(app.SynthesisPanel);
            app.GridLayout = uigridlayout(app.Panel);
            app.mEditFieldLabel = uilabel(app.GridLayout);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridLayout, 'numeric');
            app.mEditField.Value = 5;
            app.nLabel = uilabel(app.GridLayout);
            app.nLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout, 'numeric');
            app.nEditField.Value = 1;
            app.SegmentCurveButton = uibutton(app.SynthesisPanel, 'push');
            app.SegmentCurveButton.ButtonPushedFcn = createCallbackFcn(app, @segmentCurve, true);
            app.SegmentCurveButton.Text = 'Segment Curve';
            app.CurvatureSliderLabel = uilabel(app.SynthesisPanel);
            app.CurvatureSliderLabel.Text = 'Curvature';
            app.CurvatureSlider = uislider(app.SynthesisPanel);
            app.CurvatureSlider.Limits = [0 500];
            app.CurvatureSlider.ValueChangedFcn = createCallbackFcn(app, @updateCurve, true);
            app.TorsionSliderLabel = uilabel(app.SynthesisPanel);
            app.TorsionSliderLabel.Text = 'Torsion';
            app.TorsionSlider = uislider(app.SynthesisPanel);
            app.TorsionSlider.Limits = [0 500];
            app.TorsionSlider.ValueChangedFcn = createCallbackFcn(app, @updateCurve, true);
            app.ArcLengthSliderLabel = uilabel(app.SynthesisPanel);
            app.ArcLengthSliderLabel.Text = 'Arc Length';
            app.ArcLengthSlider = uislider(app.SynthesisPanel);
            app.ArcLengthSlider.Limits = [0.001 0.03];
            app.ArcLengthSlider.ValueChangedFcn = createCallbackFcn(app, @updateCurve, true);
            app.ArcLengthSlider.Value = 0.001;
            app.SelectCurveDropDownLabel = uilabel(app.SynthesisPanel);
            app.SelectCurveDropDownLabel.Text = 'Select Curve';
            app.SelectCurveDropDown = uidropdown(app.SynthesisPanel);
            app.SelectCurveDropDown.Items = {'1', 'Add'};
            app.SelectCurveDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedCurve, true);
            app.SelectCurveDropDown.Value = '1';
            app.RemoveSelectedCurveButton = uibutton(app.SynthesisPanel, 'push');
            app.RemoveSelectedCurveButton.ButtonPushedFcn = createCallbackFcn(app, @deleteCurve, true);
            app.RemoveSelectedCurveButton.Text = 'Remove Selected Curve';
            app.TDSliderLabel = uilabel(app.SynthesisPanel);
            app.TDSliderLabel.Text = 'TD';
            app.TDSlider = uislider(app.SynthesisPanel);
            app.TDSlider.Limits = [0.001 3];
            app.TDSlider.Value = 0.001;
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'Curve Generator')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.ModelSelectionPanel = uipanel(app.UIFigure);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.LoadSTLFileLabel = uilabel(app.ModelSelectionPanel);
            app.LoadSTLFileLabel.Text = 'Load .STL File';
            app.LoadFileButton = uibutton(app.ModelSelectionPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSTL, true);
            app.LoadFileButton.Text = 'Load File';
            app.STLDropDown = uidropdown(app.ModelSelectionPanel);
            app.STLDropDown.Items = {'0'};
            app.STLDropDown.ValueChangedFcn = createCallbackFcn(app, @loadSTL, true);
            app.STLDropDown.Value = '0';
            app.ClearSTLButton = uibutton(app.ModelSelectionPanel, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @removeSTL, true);
            app.ClearSTLButton.Text = 'Clear STL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynthesisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end